classdef F289DAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Table                matlab.ui.control.Table
        Panel_2              matlab.ui.container.Panel
        StatusLamp           matlab.ui.control.Lamp
        StatusLabel          matlab.ui.control.Label
        ExportGraphButton    matlab.ui.control.Button
        ClearTableButton     matlab.ui.control.Button
        ClearGraphButton     matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        GraphingSwitch       matlab.ui.control.Switch
        GraphingSwitchLabel  matlab.ui.control.Label
        PollingSwitch        matlab.ui.control.Switch
        PollingSwitchLabel   matlab.ui.control.Label
        ExportTableButton    matlab.ui.control.Button
        PollingRate          matlab.ui.control.NumericEditField
        PollingPeriodsLabel  matlab.ui.control.Label
        ConnectButton        matlab.ui.control.StateButton
        PortDropDown         matlab.ui.control.DropDown
        PortDropDownLabel    matlab.ui.control.Label
        StatusEditField      matlab.ui.control.EditField
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        meter; % meter connection
        pollingTimer %polling timer
        t0; %initial time
        tg; %last grapgh update time
        t; %time
        y1; %value1
        y2; %value2

    end

    methods (Access = private)

        function pollingFunc(app, ~, event)

            writeline(app.meter,"QDDA");
            time=datetime;

            % V_AC,NONE,AUTO,VAC,5,0,OFF,0.000,0,2,LIVE,0,VAC,0,4,5,NORMAL,NONE,1690549438.826,PRIMARY,0,VAC,0,4,5,NORMAL,NONE,1690549438.826
            a = readline(app.meter);
            a = readline(app.meter);
            if (isstring(a))
                s = strsplit(a,',');

                app.StatusEditField.Value = a;
                sec=seconds(time-app.t0);


                app.Table.Data=[app.Table.Data; [datestr(time) sec str2double(s(12)) s(13) str2double(s(21)) s(22)] ];
                scroll(app.Table,'bottom');
                h=height(app.Table.Data);
                if(h>1)
                    if(app.Table.Data(h,4)~=app.Table.Data(h-1,4) | app.Table.Data(h,6)~=app.Table.Data(h-1,6)  )
                        initializeGraph(app)
                    end
                else
                    initializeGraph(app)
                end

                app.t=[app.t sec];
                app.y1=[app.y1 str2double(s(12))];
                app.y2=[app.y2 str2double(s(21))];

                if(strcmp(app.GraphingSwitch.Value,"On")&seconds(time-app.tg)>2)
                    app.tg=time;
                    yyaxis(app.UIAxes,'left')
                    plot(app.UIAxes,app.t,app.y1,'Marker','o','MarkerSize',6)
                    yyaxis(app.UIAxes,'right')
                    plot(app.UIAxes,app.t,app.y2,'Marker','o','MarkerSize',6)
                end


            end
        end

        function initializeGraph(app)
            cla(app.UIAxes)
            app.t=[];
            app.y1=[];
            app.y2=[];
            xlabel(app.UIAxes, 'Time (s)');
            yyaxis(app.UIAxes, 'left')
            ylabel(app.UIAxes, app.Table.Data(height(app.Table.Data),4));
            yyaxis(app.UIAxes, 'right')
            ylabel(app.UIAxes, app.Table.Data(height(app.Table.Data),6));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PollingSwitch.Enable='off';
            app.PortDropDown.Items = serialportlist("all");

            %plot(app.UIAxes,app.t,app.y1);
            %   yyaxis(app.UIAxes,'right')
            %linkdata on
            %app.h=plot(app.UIAxes,app.t,app.y2);
            % linkdata on;
            %app.StatusLamp.Color='888888';

            % Create timer object
            app.pollingTimer = timer(...
                'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
                'Period', app.PollingRate.Value, ...                       % Period is 5 seconds
                'BusyMode', 'queue',...                % Queue timer callbacks when busy
                'TimerFcn', @app.pollingFunc);   % Callback that runs every period

        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)


            value = app.ConnectButton.Value;

            if(value)

                app.meter = serialport(app.PortDropDown.Value,115200,"Timeout",1);
                configureTerminator(app.meter,"CR");
                writeline(app.meter,"ID");
                if(strcmp('0',readline(app.meter)))
                    app.StatusLamp.Color=[0 1 0];
                    app.PollingSwitch.Enable='on';
                    drawnow
                    app.ConnectButton.Text = 'Disconnect';
                    app.StatusEditField.Value = strcat('Connection Successful: ',readline(app.meter));
                else
                    app.StatusLamp.Color=[1 0 0];
                    app.StatusEditField.Value = 'Error Connecting to Meter.  Check: COM Port#, Meter is On, Meter is Plugged in';
                    app.meter=[];
                    clear app.meter
                    app.ConnectButton.Value=0;
                    app.ConnectButton.Text = 'Connect';
                    app.PollingSwitch.Enable='off';

                end
            else
                stop(app.pollingTimer);
                flush(app.meter)
                app.meter=[];
                clear app.meter
                app.ConnectButton.Text = 'Connect';
                app.PollingSwitch.Enable='off';
                app.PollingSwitch.Value="Off";
                app.StatusEditField.Value = 'Disconnected from meter.';

            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.pollingTimer);
            delete(app.pollingTimer);
            clear app.meter
            delete(app)

        end

        % Value changed function: PollingSwitch
        function PollingSwitchValueChanged(app, event)
            value = strcmp(app.PollingSwitch.Value,"On")

            if(value)
                app.t0=datetime;
                app.tg=app.t0;
                stop(app.pollingTimer);
                delete(app.pollingTimer);
                app.pollingTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
                    'Period', app.PollingRate.Value, ...                       % Period is 5 seconds
                    'BusyMode', 'queue',...                % Queue timer callbacks when busy
                    'TimerFcn', @app.pollingFunc);
                start(app.pollingTimer);
                %
            else
                stop(app.pollingTimer)
            end

        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            app.t=[];
            app.y1=[];
            app.y2=[];
            cla(app.UIAxes)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StatusEditField = uieditfield(app.GridLayout, 'text');
            app.StatusEditField.Editable = 'off';
            app.Panel_2 = uipanel(app.GridLayout);
            app.PortDropDownLabel = uilabel(app.Panel_2);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.Panel_2);
            app.PortDropDown.Items = {'COM #'};
            app.PortDropDown.Value = 'COM #';
            app.ConnectButton = uibutton(app.Panel_2, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.PollingPeriodsLabel = uilabel(app.Panel_2);
            app.PollingPeriodsLabel.Text = 'Polling Period (s)';
            app.PollingRate = uieditfield(app.Panel_2, 'numeric');
            app.PollingRate.Value = 1;
            app.ExportTableButton = uibutton(app.Panel_2, 'push');
            app.ExportTableButton.Text = 'Export Table';
            app.Panel = uipanel(app.Panel_2);
            app.PollingSwitchLabel = uilabel(app.Panel);
            app.PollingSwitchLabel.Text = 'Polling';
            app.PollingSwitch = uiswitch(app.Panel, 'slider');
            app.PollingSwitch.ValueChangedFcn = createCallbackFcn(app, @PollingSwitchValueChanged, true);
            app.GraphingSwitchLabel = uilabel(app.Panel);
            app.GraphingSwitchLabel.Text = 'Graphing';
            app.GraphingSwitch = uiswitch(app.Panel, 'slider');
            app.GraphingSwitch.Value = 'On';
            app.ClearGraphButton = uibutton(app.Panel_2, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.ClearTableButton = uibutton(app.Panel_2, 'push');
            app.ClearTableButton.Text = 'Clear Table';
            app.ExportGraphButton = uibutton(app.Panel_2, 'push');
            app.ExportGraphButton.Text = 'Export Graph';
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status:';
            app.StatusLamp = uilamp(app.Panel_2);
            app.Table = uitable(app.GridLayout);
            app.Table.ColumnName = {'Date'; 'ΔTime'; 'Primary Value'; 'Primary Unit'; 'Secondary Value'; 'Secondary Unit'};
            app.Table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F289DAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end